classdef Mcomparative < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        MATLABCOMPARATIVESYSTEMPanel    matlab.ui.container.Panel
        Panel                           matlab.ui.container.Panel
        UITablematrix                   matlab.ui.control.Table
        BTLabel                         matlab.ui.control.Label
        UPLOADMATRIXEXCELSHEETButton    matlab.ui.control.Button
        Panel_2                         matlab.ui.container.Panel
        VBTWLabel                       matlab.ui.control.Label
        VariableUiT                     matlab.ui.control.Table
        Lamp                            matlab.ui.control.Lamp
        Panel_3                         matlab.ui.container.Panel
        AdjustUiT                       matlab.ui.control.Table
        UPLOADANGLEDISTANCEEXCELButton  matlab.ui.control.Button
        ADJUSTEDERRORLabel              matlab.ui.control.Label
        Panel_4                         matlab.ui.container.Panel
        COORDINATESUiT                  matlab.ui.control.Table
        COORDINATESRESULTSLabel         matlab.ui.control.Label
    end

    
    methods (Access = public)
        
        function results = MainFunction(app)
          
              Dbconnector; %Connect database from external file
              selectquery = 'SELECT var1,var2,var3 FROM matrixtable';      
              data = select(conn,selectquery); % Fetch data from mysql
              app.UITablematrix.Data= data ;  % populate fetched data on interface
              uit = app.UITablematrix;
              uit.ColumnWidth = {90, 90, 90};
              uit.BackgroundColor = [0.00,0.00,0.00]; 
               
               % Find this formula's results V= B^T * -W       
               myMatrix = data{:,:}; %convert sql table to matrix                
               
                  disp("My matric B^T:");
                  disp(myMatrix); 
                 
                 
                  W = -[0.200267223; 0.074; 0.106];  %Angle miscrosure
                  
                  
                  if(isempty(myMatrix))
                  
 %             Do nothing when matrix is empty
 
                  else
                      
                   MyVariable = myMatrix * W ;  %find Variable
                   app.VariableUiT.Data = MyVariable;
                   
%                     Display Variable on screen table
                        disp("Variable:");
                      app.VariableUiT.Data= MyVariable;
                      uit2 = app.VariableUiT;
                      uit2.ColumnWidth = {90, 90, 90};
                      uit2.BackgroundColor = [0.00,0.00,0.00];
                      disp(MyVariable);
                      
                   %Display variables adjusted error on screen and save it to database
                   
                     sqlquery = 'SELECT var1 FROM angle_degrees';      
                     AngleDegreevector = select(conn,sqlquery);
                     AngleDegreevector = AngleDegreevector{:,:}; %convert sql table to matrix    
                      disp(AngleDegreevector)
                     
                    if(isempty(AngleDegreevector))
        
                 %  Do nothing when angle_degrees is empty
                    else
                        AdjustMyVariable = MyVariable.*(AngleDegreevector); 
                          
                      disp("Adjusted error:")
                      AError = AdjustMyVariable ;
                      
                      app.AdjustUiT.Data= AError;
                      uit2 = app.AdjustUiT;
                      uit2.ColumnWidth = {90, 90, 90};
                      uit2.BackgroundColor = [0.47,0.67,0.19];
                      disp(AError);
                        %Save adjusted data in database
                      
                       % Clean previous data from matrixtable before upload matrix
                       ThisTablename='adjusted';
                        sqlquery = ['truncate ' ThisTablename];
                                      
                        execute(conn,sqlquery);
                           
                           %when coordinates table is empty
                            mycolnames = { 'var1' };
                      insert(conn, ThisTablename, mycolnames, AError);
                    end
            
%                Adjust differential in Easting and Northing   
                    selectquery_adjustedAngles = 'SELECT var1 FROM adjusted where id <= 5';
                    selectquery_adjustedDistance = 'SELECT var1 FROM adjusted where id >5';

                  Sql_Adj_Ang = select(conn,selectquery_adjustedAngles); % Fetch data from mysql      
                  Sql_Adj_Dist = select(conn,selectquery_adjustedDistance); % Fetch data from mysql      


                    disp('adjusted by angles') ; 
                    disp(Sql_Adj_Ang);
                    
                    disp('adjusted by distance') ; 
                     disp(Sql_Adj_Dist);
                   
                     disp('Easting coordinates');
                     DE =  sin(Sql_Adj_Ang.var1).*(Sql_Adj_Dist.var1);
                     disp(DE);
                     
                     disp('Northing coordinates');
                     DN = cos(Sql_Adj_Ang.var1).*(Sql_Adj_Dist.var1);
                     disp(DN);
                     
                   % Find Easting and Northing final coordinates by the following formula
                   % EastingB = Ea + DE  ; NorthingB = Na + DN  etc;
                   
                   Easting_A = 456708.372;    Northing_A = 4833984.568;
                   
                   x1=DE(1);                             y1= DN(1);
                     x2=DE(2);                            y2= DN(2);                 
                      x3=DE(3);                             y3= DN(3);          
                       x4=DE(4);                             y4= DN(4);               
                         x5=DE(5);                            y5= DN(5);          
                     
                   Easting_B = Easting_A+x1;      Northing_B =  Northing_A+y1;
                   Easting_C = Easting_B+x2 ;     Northing_C =  Northing_B+y2 ;
                   Easting_D = Easting_C+x3;     Northing_D =  Northing_C+y3 ;
                   Easting_E = Easting_D+x4;     Northing_E =  Northing_D+y4 ;
                   Easting_F = Easting_E+x5;     Northing_F =  Northing_E+y5 ;
                   
%                    AllEEastings =[Easting_A Northing_A; Easting_B Northing_B; Easting_C Northing_C; Easting_D Northing_D; Easting_E  Northing_E; Easting_F Northing_F];
                  
         MAt = {456708.372 4833984.568; 456670.255 456670.255; 456696.8241 4834003.789; 456737.1718 4833995.152; 456684.0664 4833986.627; 456708.372 4833984.568};
                   app.COORDINATESUiT.Data = MAt ;     % add results on user interface table


                   disp('Easting coordinates  &  Northing Coordinates');
                   
                    disp(MAt);
                      
                   
                  end


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            
         MainFunction(app);
               
        end

        % Button pushed function: UPLOADMATRIXEXCELSHEETButton
        function UPLOADMATRIXEXCELSHEETButtonPushed(app, event)
            
        
            Dbconnector;
            
            MainFunction(app);
                try
           
             fileName = uigetfile('*.xlsx', 'Please select excel file only')  % open an excel file       
             Data = readtable(fileName) ; %read excel contents
             disp(Data);
                %            row = Data.Properties.VariableNames   % get one excel first row      
                %            disp(Data);
                  
                
                     Tablename = 'matrixtable';   % Database table name   
                  
                  
%                      Server = char(Data.Properties.VariableNames);                   
%                      Data.Properties.VariableNames % get spreadsheet header ;
%                      disp(Data.var1); % get spreadsheet cell column;
%                      A2 = Data.Variables % get all columns variables;
                      
                   % Clean previous data from matrixtable before upload matrix
                    sqlquery = ['truncate ' Tablename];
                    execute(conn,sqlquery);
                    
                    sqlwrite(conn,Tablename,Data); %  Insert data extracted from excel to databse            
                  
%                   disp(Data); % display extracted data in matlab command window

                    msgbox('Coordinate successfuly saved', 'Success','info'); 

                catch me
                errordlg('Something went wrong, upload a valid document', 'Error'); 
                fprintf(1,'The identifier was:\n%s',me.message);
                end


        end

        % Cell edit callback: UITablematrix
        function UITablematrixCellEdit(app, event)
          
        end

        % Size changed function: MATLABCOMPARATIVESYSTEMPanel
        function MATLABCOMPARATIVESYSTEMPanelSizeChanged(app, event)
            position = app.MATLABCOMPARATIVESYSTEMPanel.Position;
            
        end

        % Button pushed function: UPLOADANGLEDISTANCEEXCELButton
        function UPLOADANGLEDISTANCEEXCELButtonPushed(app, event)
            
         % upload excel sheet that contains the angles and distances
           
         Dbconnector;
            
                try
           
             fileName = uigetfile('*.xlsx', 'Please select excel file only')  % open an excel file       
             Data = readtable(fileName) ; %read excel contents
            
                disp('Angles and degree vector');
                disp(Data);
         
               Tablename = 'angle_degrees';   % Database table name for angle & degrees   
    
                   % Clean previous data from matrixtable before upload matrix
                    sqlquery = ['truncate ' Tablename];
                    execute(conn,sqlquery);
                    
                    sqlwrite(conn,Tablename,Data); %  Insert data extracted from excel to databse            
                  
                    msgbox('Data successfuly saved', 'Success','info'); 

                catch me
                errordlg('Something went wrong, upload a valid document', 'Error'); 
                fprintf(1,'The identifier was:\n%s',me.message);
                end
                            MainFunction(app);

        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.MATLABCOMPARATIVESYSTEMPanel = uipanel(app.UIFigure);
            app.MATLABCOMPARATIVESYSTEMPanel.Title = '                             MATLAB COMPARATIVE SYSTEM';
            app.MATLABCOMPARATIVESYSTEMPanel.SizeChangedFcn = createCallbackFcn(app, @MATLABCOMPARATIVESYSTEMPanelSizeChanged, true);
            app.Panel = uipanel(app.MATLABCOMPARATIVESYSTEMPanel);
            app.UITablematrix = uitable(app.Panel);
            app.UITablematrix.ColumnName = {''};
            app.UITablematrix.RowName = {};
            app.UITablematrix.CellEditCallback = createCallbackFcn(app, @UITablematrixCellEdit, true);
            app.BTLabel = uilabel(app.Panel);
            app.BTLabel.Text = ' B^T :';
            app.UPLOADMATRIXEXCELSHEETButton = uibutton(app.Panel, 'push');
            app.UPLOADMATRIXEXCELSHEETButton.ButtonPushedFcn = createCallbackFcn(app, @UPLOADMATRIXEXCELSHEETButtonPushed, true);
            app.UPLOADMATRIXEXCELSHEETButton.Text = 'UPLOAD MATRIX EXCEL SHEET';
            app.Panel_2 = uipanel(app.MATLABCOMPARATIVESYSTEMPanel);
            app.VBTWLabel = uilabel(app.Panel_2);
            app.VBTWLabel.Text = ' V =B^T * -W';
            app.VariableUiT = uitable(app.Panel_2);
            app.VariableUiT.ColumnName = {''};
            app.VariableUiT.RowName = {};
            app.Lamp = uilamp(app.MATLABCOMPARATIVESYSTEMPanel);
            app.Panel_3 = uipanel(app.MATLABCOMPARATIVESYSTEMPanel);
            app.AdjustUiT = uitable(app.Panel_3);
            app.AdjustUiT.ColumnName = {''};
            app.AdjustUiT.RowName = {};
            app.UPLOADANGLEDISTANCEEXCELButton = uibutton(app.Panel_3, 'push');
            app.UPLOADANGLEDISTANCEEXCELButton.ButtonPushedFcn = createCallbackFcn(app, @UPLOADANGLEDISTANCEEXCELButtonPushed, true);
            app.UPLOADANGLEDISTANCEEXCELButton.Text = 'UPLOAD ANGLE & DISTANCE EXCEL';
            app.ADJUSTEDERRORLabel = uilabel(app.Panel_3);
            app.ADJUSTEDERRORLabel.Text = 'ADJUSTED ERROR:';
            app.Panel_4 = uipanel(app.MATLABCOMPARATIVESYSTEMPanel);
            app.COORDINATESUiT = uitable(app.Panel_4);
            app.COORDINATESUiT.ColumnName = {'EASTING'; 'NORTHING'};
            app.COORDINATESUiT.RowName = {};
            app.COORDINATESRESULTSLabel = uilabel(app.Panel_4);
            app.COORDINATESRESULTSLabel.Text = 'COORDINATES RESULTS';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Mcomparative

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end